classdef app11 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UITable         matlab.ui.control.Table
        ReadDataButton  matlab.ui.control.Button
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadDataButton
        function ReadDataButtonPushed(app, event)
            t = readtable("Excel.xlsx", "Sheet", 1);
            app.UITable.Data = t;
            t.Properties.VariableNames{1} = 'x Data';
            t.Properties.VariableNames{2} = 'y Data';
            app.UITable.ColumnName = t.Properties.VariableNames;
            x = table2array(t(:, "x Data"));
            y = table2array(t(:, "y Data"));
            d = y.^2;
            plot(app.UIAxes, x, y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.ReadDataButton = uibutton(app.UIFigure, 'push');
            app.ReadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDataButtonPushed, true);
            app.ReadDataButton.Text = 'Read Data';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'y  = x^2')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app11

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end