classdef app15 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UITable                  matlab.ui.control.Table
        RowNumberEditFieldLabel  matlab.ui.control.Label
        RowNumberEditField       matlab.ui.control.NumericEditField
        MinAgeEditFieldLabel     matlab.ui.control.Label
        MinAgeEditField          matlab.ui.control.NumericEditField
        DeleteButton             matlab.ui.control.Button
        FilterAgeButton          matlab.ui.control.Button
        ReadDataButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        t %Table to share between callbacks
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FilterAgeButton
        function FilterAgeButtonPushed(app, event)
            filterAge = app.MinAgeEditField.Value;
            
            numRows = size(app.t, 1);
            
            k = 1;
            
            for i = 1:numRows
                if app.UITable.Data {i, 2} > filterAge
                    RowstoDel(1, k) = i;
                    k = k + 1;
                end
            end
            
            app.UITable.Data(RowstoDel, :) = [];
        end

        % Button pushed function: ReadDataButton
        function ReadDataButtonPushed(app, event)
            app.t = readtable('NameAge.xlsx', 'Sheet', 1);
            app.UITable.Data = app.t;
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            r = app.RowNumberEditField.Value;
            
            app.UITable.Data (r, :) = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.RowNumberEditFieldLabel = uilabel(app.UIFigure);
            app.RowNumberEditFieldLabel.Text = 'Row Number';
            app.RowNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinAgeEditFieldLabel = uilabel(app.UIFigure);
            app.MinAgeEditFieldLabel.Text = 'Min Age';
            app.MinAgeEditField = uieditfield(app.UIFigure, 'numeric');
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.FilterAgeButton = uibutton(app.UIFigure, 'push');
            app.FilterAgeButton.ButtonPushedFcn = createCallbackFcn(app, @FilterAgeButtonPushed, true);
            app.FilterAgeButton.Text = 'Filter Age';
            app.ReadDataButton = uibutton(app.UIFigure, 'push');
            app.ReadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDataButtonPushed, true);
            app.ReadDataButton.Text = 'Read Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app15

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end