classdef app17 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Gauge            matlab.ui.control.Gauge
        GONELabel        matlab.ui.control.Label
        SAVEDUFFFLabel   matlab.ui.control.Label
        JUSTOKLabel      matlab.ui.control.Label
        BONUSLabel       matlab.ui.control.Label
        WOWJACKPOTLabel  matlab.ui.control.Label
        PLAYButton       matlab.ui.control.Button
        Spinner          matlab.ui.control.Spinner
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            for i = 1:20
                pause(0.5);
                r = randi([-100 100]);
                app.Gauge.Value = r;
                app.Spinner.Value = r;
            end
            
            app.Gauge.BackgroundColor = [1, 0, 0]; %red color
            pause(1);
            app.Gauge.BackgroundColor = [0, 1, 0]; %green color
            pause(1);
            app.Gauge.BackgroundColor = [0, 0, 1]; %blue color
            pause(1);
            app.Gauge.BackgroundColor = [1, 1, 1]; %default color
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge.Limits = [-100 100];
            app.GONELabel = uilabel(app.UIFigure);
            app.GONELabel.Text = 'GONE!!!';
            app.SAVEDUFFFLabel = uilabel(app.UIFigure);
            app.SAVEDUFFFLabel.Text = 'SAVED ... UFFF!!!';
            app.JUSTOKLabel = uilabel(app.UIFigure);
            app.JUSTOKLabel.Text = 'JUST ... OK!!!';
            app.BONUSLabel = uilabel(app.UIFigure);
            app.BONUSLabel.Text = 'BONUS!!!';
            app.WOWJACKPOTLabel = uilabel(app.UIFigure);
            app.WOWJACKPOTLabel.Text = 'WOW ... JACKPOT!!!';
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.Spinner = uispinner(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app17

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end