classdef app19 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TextArea       matlab.ui.control.TextArea
        Button         matlab.ui.control.Button
        Button_4       matlab.ui.control.Button
        Button_5       matlab.ui.control.Button
        Button_2       matlab.ui.control.Button
        Button_6       matlab.ui.control.Button
        Button_7       matlab.ui.control.Button
        Button_3       matlab.ui.control.Button
        Button_8       matlab.ui.control.Button
        Button_9       matlab.ui.control.Button
        Button_10      matlab.ui.control.Button
        ClearButton    matlab.ui.control.Button
        Button_12      matlab.ui.control.Button
        Button_13      matlab.ui.control.Button
        Button_14      matlab.ui.control.Button
        SineButton     matlab.ui.control.Button
        CosineButton   matlab.ui.control.Button
        BasicButton    matlab.ui.control.Button
        ButtonGroup    matlab.ui.container.ButtonGroup
        RadanceButton  matlab.ui.control.RadioButton
        DegreeButton   matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button, Button_10, Button_12, 
        % Button_13, Button_2, Button_3, Button_4, Button_5, 
        % Button_6, Button_7, Button_8, Button_9
        function buttonPressed(app, event)
            app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text];
        end

        % Button pushed function: ClearButton
        function buttonClear(app, event)
            app.TextArea.Value = '';
        end

        % Button pushed function: Button_14
        function buttonEqual(app, event)
            try
                app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text ...
                    num2str(eval(app.TextArea.Value{1}))];
            catch
                app.TextArea.Value = 'Error!';
            end
                

        end

        % Button pushed function: BasicButton
        function BasicButtonPushed(app, event)
            switch(app.BasicButton.Text)
                case 'Basic'
                    app.BasicButton.Text = 'Advance';
                    app.SineButton.Visible = 'off';
                    app.CosineButton.Visible = 'off';
                    app.ButtonGroup.Visible = 'off';
                case 'Advance'
                    app.BasicButton.Text = 'Basic';
                    app.SineButton.Visible = 'on';
                    app.CosineButton.Visible = 'on';
                    app.ButtonGroup.Visible = 'on';
            end
        end

        % Button pushed function: SineButton
        function SineButtonPushed(app, event)
            inputValue = str2double(app.TextArea.Value{1});
            
            if(app.DegreeButton.Value)
                inputValue = pi*inputValue/180;
            end
            
            app.TextArea.Value = num2str(sin(inputValue));
        end

        % Button pushed function: CosineButton
        function CosineButtonPushed(app, event)
            inputValue = str2double(app.TextArea.Value{1});
            
            if(app.DegreeButton.Value)
                inputValue = pi*inputValue/180;
            end
            
            app.TextArea.Value = num2str(cos(inputValue));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'0'};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @buttonPressed, true);
            app.Button.Text = '1';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @buttonPressed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @buttonPressed, true);
            app.Button_5.Text = '5';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @buttonPressed, true);
            app.Button_2.Text = '2';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @buttonPressed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @buttonPressed, true);
            app.Button_7.Text = '7';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @buttonPressed, true);
            app.Button_3.Text = '3';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @buttonPressed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @buttonPressed, true);
            app.Button_9.Text = '9';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @buttonPressed, true);
            app.Button_10.Text = '0';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @buttonClear, true);
            app.ClearButton.Text = 'Clear';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @buttonPressed, true);
            app.Button_12.Text = '+';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @buttonPressed, true);
            app.Button_13.Text = '-';
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @buttonEqual, true);
            app.Button_14.Text = '=';
            app.SineButton = uibutton(app.UIFigure, 'push');
            app.SineButton.ButtonPushedFcn = createCallbackFcn(app, @SineButtonPushed, true);
            app.SineButton.Text = 'Sine';
            app.CosineButton = uibutton(app.UIFigure, 'push');
            app.CosineButton.ButtonPushedFcn = createCallbackFcn(app, @CosineButtonPushed, true);
            app.CosineButton.Text = 'Cosine';
            app.BasicButton = uibutton(app.UIFigure, 'push');
            app.BasicButton.ButtonPushedFcn = createCallbackFcn(app, @BasicButtonPushed, true);
            app.BasicButton.Text = 'Basic';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.RadanceButton = uiradiobutton(app.ButtonGroup);
            app.RadanceButton.Text = 'Radance';
            app.RadanceButton.Value = true;
            app.DegreeButton = uiradiobutton(app.ButtonGroup);
            app.DegreeButton.Text = 'Degree';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app19

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end