classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        NewtonsCalculatorLabel  matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        CalculateButton         matlab.ui.control.Button
        MassKgEditFieldLabel    matlab.ui.control.Label
        MassKgEditField         matlab.ui.control.NumericEditField
        Accms2EditFieldLabel    matlab.ui.control.Label
        Accms2EditField         matlab.ui.control.NumericEditField
        ForceNEditFieldLabel    matlab.ui.control.Label
        ForceNEditField         matlab.ui.control.NumericEditField
        Lamp                    matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            Mass  = app.MassKgEditField.Value;
            Acc = app.Accms2EditField.Value;
            Force = Mass*Acc;
            app.ForceNEditField.Value = Force;
            
            if Force >= 0 && Force <= 1000
                app.Lamp.Color = [0.00,1.00,0.00];
            elseif Force > 1000 && Force <=5000
                app.Lamp.Color = [1.00,1.00,0.07];
            else
                app.Lamp.Color = 'red';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NewtonsCalculatorLabel = uilabel(app.UIFigure);
            app.NewtonsCalculatorLabel.Text = 'Newton''s Calculator';
            app.Panel = uipanel(app.UIFigure);
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MassKgEditFieldLabel = uilabel(app.Panel);
            app.MassKgEditFieldLabel.Text = 'Mass (Kg)';
            app.MassKgEditField = uieditfield(app.Panel, 'numeric');
            app.Accms2EditFieldLabel = uilabel(app.Panel);
            app.Accms2EditFieldLabel.Text = 'Acc (m/s^2)';
            app.Accms2EditField = uieditfield(app.Panel, 'numeric');
            app.ForceNEditFieldLabel = uilabel(app.Panel);
            app.ForceNEditFieldLabel.Text = 'Force (N)';
            app.ForceNEditField = uieditfield(app.Panel, 'numeric');
            app.Lamp = uilamp(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end