classdef app21 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        EditField                  matlab.ui.control.NumericEditField
        EditField2                 matlab.ui.control.NumericEditField
        OperationSelectionPanel    matlab.ui.container.Panel
        ShowMultiplicationDivisionOptionsCheckBox  matlab.ui.control.CheckBox
        ButtonGroup                matlab.ui.container.ButtonGroup
        AdditionButton             matlab.ui.control.RadioButton
        SubstractionButton         matlab.ui.control.RadioButton
        ButtonGroup_2              matlab.ui.container.ButtonGroup
        MultiplicationButton       matlab.ui.control.RadioButton
        DivisionButton             matlab.ui.control.RadioButton
        ComputeButton              matlab.ui.control.Button
        ResultFormatDropDownLabel  matlab.ui.control.Label
        ResultFormatDropDown       matlab.ui.control.DropDown
        ResultTextAreaLabel        matlab.ui.control.Label
        ResultTextArea             matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: 
        % ShowMultiplicationDivisionOptionsCheckBox
        function ShowMultiplicationDivisionOptionsCheckBoxValueChanged(app, event)
            value = app.ShowMultiplicationDivisionOptionsCheckBox.Value;
            
            switch value
                case 0
                    app.AdditionButton.Visible ='on';
                    app.SubstractionButton.Visible = 'on';
                    app.MultiplicationButton.Visible = 'off';
                    app.DivisionButton.Visible = 'off';
                case 1
                    app.AdditionButton.Visible ='off';
                    app.SubstractionButton.Visible = 'off';
                    app.MultiplicationButton.Visible = 'on';
                    app.DivisionButton.Visible = 'on';
            end
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            result = 0;
            
            switch app.ShowMultiplicationDivisionOptionsCheckBox.Value
                case 0
                    if(app.AdditionButton.Value)
                        %Addition operation
                        result = app.EditField.Value + app.EditField2.Value;
                    end
                    
                    if(app.SubstractionButton.Value)
                        %Substraction operation
                        result = app.EditField.Value - app.EditField2.Value;
                    end
                    
                case 1
                    if(app.MultiplicationButton.Value)
                        %Multiplication operation
                        result = app.EditField.Value * app.EditField2.Value;
                    end
                    
                    if(app.DivisionButton.Value)
                        %Substraction operation
                        result = app.EditField.Value / app.EditField2.Value;
                    end    
            end
            
            if(strcmp(app.ResultFormatDropDown.Value, 'Numeric'))
                app.ResultTextArea.Value = num2str(result);
            else
                app.ResultTextArea.Value = ['"' num2str(result) '"'];
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.OperationSelectionPanel = uipanel(app.UIFigure);
            app.OperationSelectionPanel.Title = 'Operation Selection';
            app.ShowMultiplicationDivisionOptionsCheckBox = uicheckbox(app.OperationSelectionPanel);
            app.ShowMultiplicationDivisionOptionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowMultiplicationDivisionOptionsCheckBoxValueChanged, true);
            app.ShowMultiplicationDivisionOptionsCheckBox.Text = 'Show Multiplication Division Options';
            app.ButtonGroup = uibuttongroup(app.OperationSelectionPanel);
            app.AdditionButton = uiradiobutton(app.ButtonGroup);
            app.AdditionButton.Text = 'Addition';
            app.AdditionButton.Value = true;
            app.SubstractionButton = uiradiobutton(app.ButtonGroup);
            app.SubstractionButton.Text = 'Substraction';
            app.ButtonGroup_2 = uibuttongroup(app.OperationSelectionPanel);
            app.MultiplicationButton = uiradiobutton(app.ButtonGroup_2);
            app.MultiplicationButton.Visible = 'off';
            app.MultiplicationButton.Text = 'Multiplication';
            app.MultiplicationButton.Value = true;
            app.DivisionButton = uiradiobutton(app.ButtonGroup_2);
            app.DivisionButton.Visible = 'off';
            app.DivisionButton.Text = 'Division';
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.ResultFormatDropDownLabel = uilabel(app.UIFigure);
            app.ResultFormatDropDownLabel.Text = 'Result Format';
            app.ResultFormatDropDown = uidropdown(app.UIFigure);
            app.ResultFormatDropDown.Items = {'Numeric', 'Text'};
            app.ResultFormatDropDown.Value = 'Numeric';
            app.ResultTextAreaLabel = uilabel(app.UIFigure);
            app.ResultTextAreaLabel.Text = 'Result';
            app.ResultTextArea = uitextarea(app.UIFigure);
            app.ResultTextArea.Value = {'0'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app21

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end