classdef app23 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        StartButton      matlab.ui.control.Button
        TotalCountLabel  matlab.ui.control.Label
        Label            matlab.ui.control.Label
    end

    
    properties (Access = private)
        TotalCount = 0; % Variable to store the counted value
    end
    
    methods (Access = private)
        
        function results = increment(app)
            results = app.TotalCount + 1;
        end
        function results = decrement(app)
            results = app.TotalCount - 1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            for i = 1:20
                switch randi(2)
                    case 1
                        %increment
                        app.TotalCount = app.increment();
                    case 2
                        %decrement
                        app.TotalCount = app.decrement();
                end
                app.Label.Text = num2str(app.TotalCount);
                pause(1);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TotalCountLabel = uilabel(app.UIFigure);
            app.TotalCountLabel.Text = 'Total Count';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app23

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end