classdef app25 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TOEditFieldLabel       matlab.ui.control.Label
        TOEditField            matlab.ui.control.EditField
        SubjectEditFieldLabel  matlab.ui.control.Label
        SubjectEditField       matlab.ui.control.EditField
        SendButton             matlab.ui.control.Button
        BodyTextAreaLabel      matlab.ui.control.Label
        BodyTextArea           matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            setpref('Internet', 'SMTP_Server', 'smtp@gmail.com');
            setpref('Internet', 'E_mail', 'myemail@gmail.com');
            
            props = java.lang.System.getProperties;
            props.setProperty('mail.smtp.auth', 'true');
            props.setProperty('mail.smtp.starttls.enable', 'true');
            
            setpref('Internet', 'SMTP_Username', 'myemail@gmail.com');
            setpref('Internet','SMTP_Password', '********'); %Need to give the password

            sendmail(app.TOEditField.Value, app.SubjectEditField.Value, app.BodyTextArea.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TOEditFieldLabel = uilabel(app.UIFigure);
            app.TOEditFieldLabel.Text = 'TO';
            app.TOEditField = uieditfield(app.UIFigure, 'text');
            app.SubjectEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.UIFigure, 'text');
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.BodyTextAreaLabel = uilabel(app.UIFigure);
            app.BodyTextAreaLabel.Text = 'Body';
            app.BodyTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app25

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end