classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SensorInputEditFieldLabel  matlab.ui.control.Label
        SensorInputEditField       matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SensorInputEditField
        function SensorInputEditFieldValueChanged(app, event)
            value = app.SensorInputEditField.Value;
            
            switch value 
                case 0
                    app.Lamp.Color = [0.90,0.90,0.90];
                    app.Label.Text = 'Not Active';
                case 1
                    app.Lamp.Color = [0.00,1.00,0.00];
                    app.Label.Text = 'Active';
                case 2
                    app.Lamp.Color = [1.00,0.00,0.00];
                    app.Label.Text = 'Error';
                otherwise
                    app.Lamp.Color = [0.00,0.00,0.00];
                    app.Label.Text = 'State Unknown';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SensorInputEditFieldLabel = uilabel(app.UIFigure);
            app.SensorInputEditFieldLabel.Text = 'Sensor Input';
            app.SensorInputEditField = uieditfield(app.UIFigure, 'numeric');
            app.SensorInputEditField.ValueChangedFcn = createCallbackFcn(app, @SensorInputEditFieldValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end