classdef app5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        KnobLabel    matlab.ui.control.Label
        Knob         matlab.ui.control.Knob
        EditField    matlab.ui.control.NumericEditField
        Knob2Label   matlab.ui.control.Label
        Knob2        matlab.ui.control.DiscreteKnob
        EditField_2  matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            
            app.EditField.Value = value;
        end

        % Value changing function: Knob
        function KnobValueChanging(app, event)
            changingValue = event.Value;
            
            app.EditField.Value = changingValue;
        end

        % Value changed function: Knob2
        function Knob2ValueChanged(app, event)
            value = app.Knob2.Value;
            
            if strcmp(value, 'Off')
                app.EditField_2.Value = 'Off';
            elseif strcmp(value, 'Low')
                app.EditField_2.Value = 'Low';
            elseif strcmp(value, 'Medium')
                app.EditField_2.Value = 'Medium';
            else
                app.EditField_2.Value = 'Hİgh';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.ValueChangingFcn = createCallbackFcn(app, @KnobValueChanging, true);
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.Knob2Label = uilabel(app.UIFigure);
            app.Knob2Label.Text = 'Knob2';
            app.Knob2 = uiknob(app.UIFigure, 'discrete');
            app.Knob2.ValueChangedFcn = createCallbackFcn(app, @Knob2ValueChanged, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end